classdef TowamKomAppVer2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        GridLayout                 matlab.ui.container.GridLayout
        LeftPanel                  matlab.ui.container.Panel
        Label                      matlab.ui.control.Label
        StartButton                matlab.ui.control.Button
        Image                      matlab.ui.control.Image
        LiczbamiastEditField       matlab.ui.control.NumericEditField
        LiczbamiastEditFieldLabel  matlab.ui.control.Label
        RightPanel                 matlab.ui.container.Panel
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    methods (Access = private)
        
        function p=GenerujTablicePolaczen(l, n, zerowy_poziom_fer, miasta)

x=1;
p=zeros(n,10);


%%
fer=zerowy_poziom_fer;
for i=1:l
    for k=1:(l+1-i);
        p(x,1)=x; %indeks polaczenia
        p(x,2)=i; %indeks miasta 1
        p(x,3)=k+i; %indeks miasta 2
        p(x,4)=fer; %feromon
        p(x,5)=sqrt((miasta(p(x,2),2)-miasta(p(x,3),2))^2+(miasta(p(x,2),3)-miasta(p(x,3),3))^2);
        p(x,6)=0;
        p(x,7)=miasta(p(x,2),2);
        p(x,8)=miasta(p(x,3),2);
        p(x,9)=miasta(p(x,2),3);
        p(x,10)=miasta(p(x,3),3);
        x=x+1;
    end
end
clear x

end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartButton
        function startProgram(app, event)
            %% Parametry sterowania   
feromon_na_mrowke=50;
parowanie_feromonu=0.2;
zerowy_poziom_fer=8;

liczba_cykli=200;    
liczba_iteracji=100; 
ile=app.LiczbamiastEditField.Value;
figure;
img = imread('zdjecie.png');
imshow(img)
hold on
[x,y] = ginput(ile)
a=1:ile;
a=a';
img=[a,x,y];
img(:,2:3)=img(:,2:3)*1;
miasta=img;
scatter(miasta(:,2,:), miasta(:,3,:));
xlim([0 600]);
ylim([0 600]);
grid on;
liczbam=size(miasta);
liczbam(:,2)=[];
best_droga=inf;
mat_best_ant=[];
mat_best_droga=[];
iteracja=[];
f=1;
i=0;
hold on

n=((liczbam*(liczbam-3))/2)+liczbam;
l=liczbam-1;
p=GenerujTablicePolaczen(l, n, zerowy_poziom_fer, miasta);

mat_ant=zeros(liczba_cykli*liczba_iteracji,ile);
mat_droga=zeros(liczba_cykli*liczba_iteracji,1);


for xx=1:liczba_iteracji % okrsla liczbe iteracji
for cycle=1:liczba_cykli % okresla liczbe mrowek
ant(1,1)=1;
dostepne_miasta=miasta(:,1);
dostepne_miasta(1,:)=[];
size_p=size(p);


for cykl=1:l
size_ant=size(ant);
dostepne_polaczenia(:,2)=dostepne_miasta(:,1); 
dostepne_polaczenia(:,1)=ant(size_ant(1,1),1); 

size_dostepne_polaczenia=size(dostepne_polaczenia);

for i=1:size_dostepne_polaczenia(1,1) 
    for k=1:size_p(1,1)
        if (((dostepne_polaczenia(i,1)==p(k,2)) && (dostepne_polaczenia(i,2)==p(k,3))) || ((dostepne_polaczenia(i,2)==p(k,2)) && (dostepne_polaczenia(i,1)==p(k,3))))
            dostepne_polaczenia(i,4)=p(k,4);
            dostepne_polaczenia(i,3)=p(k,1); 
   
        end
    end
end

suma_feromonu_dostepne_polaczenia=sum(dostepne_polaczenia(:,4));
dostepne_polaczenia(:,6)=0;
for i=1:size_dostepne_polaczenia(1,1)
    dostepne_polaczenia(i,5)=dostepne_polaczenia(i,4)/suma_feromonu_dostepne_polaczenia;
    dostepne_polaczenia(i,6)=sum(dostepne_polaczenia(:,5));
end
prog_dol=[0 ; dostepne_polaczenia(:,6)];
size_prog_dol=size(prog_dol);
prog_dol(size_prog_dol(1,1))=[];
dostepne_polaczenia(:,5)=prog_dol;
los=rand();
for i=1:size_dostepne_polaczenia(1,1)
    if (dostepne_polaczenia(i,5)<los) && (los<=dostepne_polaczenia(i,6))
        ostatnie_miasto=dostepne_polaczenia(i,2);
    end
end

ant=[ant; ostatnie_miasto];

size_dostepne_miasta=size(dostepne_miasta);

for ii=1:size_dostepne_miasta(1,1)
    if ostatnie_miasto(1,1)==dostepne_miasta(ii,1)
      
        kill=ii;
    end
end

dostepne_miasta(kill,:)=[];

clear dostepne_polaczenia ii

end

clear dostepne_miasta

%% Okreslanie polaczen mrowki

polaczenia_ant1=ant;
polaczenia_ant2=ant;
size_ant=size(ant);

polaczenia_ant1(size_ant(1,1),:)=[];
polaczenia_ant2(1,:)=[];
polaczenia_ant=[polaczenia_ant1 polaczenia_ant2];

clear polaczenia_ant1 polaczenia_ant2
size_polaczenia_ant=size(polaczenia_ant);
for i=1:size_polaczenia_ant(1,1)
    for k=1:size_p(1,1)
        if ((p(k,2)==polaczenia_ant(i,1)) && (p(k,3)==polaczenia_ant(i,2))) || ((p(k,2)==polaczenia_ant(i,2)) && (p(k,3)==polaczenia_ant(i,1)))
            polaczenia_ant(i,3)=p(k,5);
        end
    end
end
droga=sum(polaczenia_ant(:,3));
feromon=feromon_na_mrowke/droga;


for i=1:size_polaczenia_ant(1,1)
    for k=1:size_p(1,1)
        if ((p(k,2)==polaczenia_ant(i,1)) && (p(k,3)==polaczenia_ant(i,2))) || ((p(k,2)==polaczenia_ant(i,2)) && (p(k,3)==polaczenia_ant(i,1)))
            p(k,6)=p(k,6)+feromon;
        end
    end
end

if droga<best_droga
    best_droga=droga;
    best_ant=ant;
    mat_best_ant=[mat_best_ant best_ant];
    mat_best_droga=[mat_best_droga best_droga];
    iteracja=[iteracja xx];
end


ant=ant';
mat_ant(f,:)=ant;
mat_droga(f,:)=droga;
f=f+1;
p(:,4)=p(:,4)+p(:,6);
p(:,6)=0;
clear ant polaczenia_ant
end % KONIEC MROWKI
%parowanie feromonu
parowanie=parowanie_feromonu;
for iii=1:size_p(1,1)
    if p(iii,4)<parowanie
        p(iii,4)=zerowy_poziom_fer;
       
    else
        p(iii,4)=p(iii,4)-parowanie;
        
    end
end
    
end
p
x=1:ile;
y=1:ile;
for a=1:ile
     i = best_ant(a);
     disp(i)
     x(a)=img(i,2);
     y(a)=img(i,3);
end
xy=[y' ,x'];
plot(x,y,'b-')

p;
        end

        % Value changed function: LiczbamiastEditField
        function LiczbamiastEditFieldValueChanged(app, event)

        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {595, 595};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {299, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.LiczbamiastEditFieldLabel = uilabel(app.LeftPanel);
            app.LiczbamiastEditFieldLabel.Text = 'Liczba miast';
            app.LiczbamiastEditField = uieditfield(app.LeftPanel, 'numeric');
            app.LiczbamiastEditField.ValueChangedFcn = createCallbackFcn(app, @LiczbamiastEditFieldValueChanged, true);
            app.Image = uiimage(app.LeftPanel);
            app.Image.ImageSource = '1198052.png';
            app.StartButton = uibutton(app.LeftPanel, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @startProgram, true);
            app.StartButton.Text = {'Start'; ''};
            app.Label = uilabel(app.LeftPanel);
            app.Label.Text = {'Instrukcja obsługi:'; '1. Podaj liczbę miast.'; '2. Wciśnij przcisk ''Start'''; '3. Zaznacz miasta.'; '4.Wynik jest gotowy.'};
            app.RightPanel = uipanel(app.GridLayout);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TowamKomAppVer2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end